classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image                     matlab.ui.control.Image
        MapNameTextArea           matlab.ui.control.TextArea
        SaveMapButton             matlab.ui.control.Button
        LoadMapButton             matlab.ui.control.Button
        TopPathButton             matlab.ui.control.Button
        TopologicalPathEditField  matlab.ui.control.EditField
        TopologicalPathLabel      matlab.ui.control.Label
        SelectModeDropDown        matlab.ui.control.DropDown
        SelectModeLabel           matlab.ui.control.Label
        EndLocationLabel          matlab.ui.control.Label
        EndLocationX_TextBox      matlab.ui.control.TextArea
        Label_4                   matlab.ui.control.Label
        EndLocationY_TextBox      matlab.ui.control.TextArea
        Label_3                   matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        StartLocationY_TextBox    matlab.ui.control.TextArea
        Label                     matlab.ui.control.Label
        StartLocationX_TextBox    matlab.ui.control.TextArea
        StartLocationLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        topPath % Stores the topological path
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SelectModeDropDown
        function SelectModeDropDownValueChanged(app, event)
            value = app.SelectModeDropDown.Value;
            if (value == "Metric Path" || value == "Localization") 
                % Make start location visible
                app.StartLocationLabel.Visible = 'on';
                app.StartLocationX_TextBox.Visible = 'on';
                app.Label.Visible = 'on';
                app.StartLocationY_TextBox.Visible = 'on';
                app.Label_2.Visible = 'on';
                % Make end location visible
                app.EndLocationLabel.Visible = 'on';
                app.EndLocationX_TextBox.Visible = 'on';
                app.Label_4.Visible = 'on';
                app.EndLocationY_TextBox.Visible = 'on';
                app.Label_3.Visible = 'on';
                % Make Topological Path boxes not visible
                app.topPath = '';
                app.TopologicalPathEditField.Value = '';
                app.TopologicalPathLabel.Visible = 'off';
                app.TopologicalPathEditField.Visible = 'off';
            elseif (value == "Topological Path")
                % Make start location not visible
                app.StartLocationLabel.Visible = 'off';
                app.StartLocationX_TextBox.Visible = 'off';
                app.Label.Visible = 'off';
                app.StartLocationY_TextBox.Visible = 'off';
                app.Label_2.Visible = 'off';
                % Make end location not visible
                app.EndLocationLabel.Visible = 'off';
                app.EndLocationX_TextBox.Visible = 'off';
                app.Label_4.Visible = 'off';
                app.EndLocationY_TextBox.Visible = 'off';
                app.Label_3.Visible = 'off';
                % Make Topological Path boxes visible
                app.TopologicalPathLabel.Visible = 'on';
                app.TopologicalPathEditField.Visible = 'on';
            else
                % Make start location not visible
                app.StartLocationLabel.Visible = 'off';
                app.StartLocationX_TextBox.Visible = 'off';
                app.Label.Visible = 'off';
                app.StartLocationY_TextBox.Visible = 'off';
                app.Label_2.Visible = 'off';
                % Make end location not visible
                app.EndLocationLabel.Visible = 'off';
                app.EndLocationX_TextBox.Visible = 'off';
                app.Label_4.Visible = 'off';
                app.EndLocationY_TextBox.Visible = 'off';
                app.Label_3.Visible = 'off';
                % Make Topological Path boxes not visible
                app.topPath = '';
                app.TopologicalPathEditField.Value = '';
                app.TopologicalPathLabel.Visible = 'off';
                app.TopologicalPathEditField.Visible = 'off';
            end
        end

        % Button pushed function: TopPathButton
        function TopPathButtonPushed(app, event)
            % Send info to robot
        end

        % Value changing function: TopologicalPathEditField
        function TopologicalPathEditFieldValueChanging(app, event)
            app.topPath = event.Value;
%             app.topPath = app.TopologicalPathEditField.Value;
            app.TopPathButton.Visible = 'on';
        end

        % Button pushed function: LoadMapButton
        function LoadMapButtonPushed(app, event)
            run testPathGeneration.m
            
%             global pathMap;
%             imshow(pathMap, 'parent', app.FiringRate);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartLocationLabel = uilabel(app.UIFigure);
            app.StartLocationLabel.Visible = 'off';
            app.StartLocationLabel.Text = 'Start Location: ( ';
            app.StartLocationX_TextBox = uitextarea(app.UIFigure);
            app.StartLocationX_TextBox.Visible = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = ', ';
            app.StartLocationY_TextBox = uitextarea(app.UIFigure);
            app.StartLocationY_TextBox.Visible = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = ')';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = ')';
            app.EndLocationY_TextBox = uitextarea(app.UIFigure);
            app.EndLocationY_TextBox.Visible = 'off';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Visible = 'off';
            app.Label_4.Text = ', ';
            app.EndLocationX_TextBox = uitextarea(app.UIFigure);
            app.EndLocationX_TextBox.Visible = 'off';
            app.EndLocationLabel = uilabel(app.UIFigure);
            app.EndLocationLabel.Visible = 'off';
            app.EndLocationLabel.Text = 'End Location: ( ';
            app.SelectModeLabel = uilabel(app.UIFigure);
            app.SelectModeLabel.Text = 'Select Mode';
            app.SelectModeDropDown = uidropdown(app.UIFigure);
            app.SelectModeDropDown.Items = {'Main', 'Topological Path', 'Metric Path', 'Localization', 'Mapping'};
            app.SelectModeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectModeDropDownValueChanged, true);
            app.SelectModeDropDown.Value = 'Main';
            app.TopologicalPathLabel = uilabel(app.UIFigure);
            app.TopologicalPathLabel.Visible = 'off';
            app.TopologicalPathLabel.Text = 'Topological Path:';
            app.TopologicalPathEditField = uieditfield(app.UIFigure, 'text');
            app.TopologicalPathEditField.ValueChangingFcn = createCallbackFcn(app, @TopologicalPathEditFieldValueChanging, true);
            app.TopologicalPathEditField.Visible = 'off';
            app.TopPathButton = uibutton(app.UIFigure, 'push');
            app.TopPathButton.ButtonPushedFcn = createCallbackFcn(app, @TopPathButtonPushed, true);
            app.TopPathButton.Visible = 'off';
            app.TopPathButton.Text = 'Send Path';
            app.LoadMapButton = uibutton(app.UIFigure, 'push');
            app.LoadMapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMapButtonPushed, true);
            app.LoadMapButton.Text = 'Load Map';
            app.SaveMapButton = uibutton(app.UIFigure, 'push');
            app.SaveMapButton.Text = 'Save Map';
            app.MapNameTextArea = uitextarea(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end